classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        BSlider          matlab.ui.control.Slider
        BSliderLabel     matlab.ui.control.Label
        ASlider          matlab.ui.control.Slider
        ASliderLabel     matlab.ui.control.Label
        RunSimButton     matlab.ui.control.Button
        CEditField       matlab.ui.control.NumericEditField
        CEditFieldLabel  matlab.ui.control.Label
        BEditField       matlab.ui.control.NumericEditField
        BEditFieldLabel  matlab.ui.control.Label
        AEditField       matlab.ui.control.NumericEditField
        AEditFieldLabel  matlab.ui.control.Label
        SineWaveTab      matlab.ui.container.Tab
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)
            %store the values for inputs 
            %A = app.AEditField.Value;
            %B = app.BEditField.Value;
           
            %sliders 
            A = app.ASlider.Value;
            B = app.BSlider.Value;

            Model = 'Adder';
            %BlockPathA = 'Adder/A';
            %BlockPathB = 'Adder/B';
            %Load the system to memory 
            load_system(Model);

            %specify inputs for the simulation 
            SimIn = Simulink.SimulationInput(Model);
  
            %simIn = simIn.setBlockParameter(BlockPathA,'A',A);
            %simIn = simIn.setBlockParameter(BlockPathB,'B',B);
            SimIn = SimIn.setVariable('A',A,'Workspace',Model);
            SimIn = SimIn.setVariable('B',B,'Workspace',Model);
            %simIn = setBlockParameter()

            %Simulate model and store the output in out 

            out = sim(SimIn);
            Sum = out.yout{1}.Values.Data;

            app.CEditField.Value = Sum;
            %Plot values of a second output port:
            plot(app.UIAxes,out.yout{2}.Values.Time,out.yout{2}.Values.Data);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.AEditFieldLabel = uilabel(app.Tab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.Tab, 'numeric');
            app.BEditFieldLabel = uilabel(app.Tab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.Tab, 'numeric');
            app.CEditFieldLabel = uilabel(app.Tab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.Tab, 'numeric');
            app.CEditField.Editable = 'off';
            app.RunSimButton = uibutton(app.Tab, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'Run Sim';
            app.ASliderLabel = uilabel(app.Tab);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.Tab);
            app.BSliderLabel = uilabel(app.Tab);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.Tab);
            app.SineWaveTab = uitab(app.TabGroup);
            app.SineWaveTab.Title = 'Sine Wave';
            app.UIAxes = uiaxes(app.SineWaveTab);
            title(app.UIAxes, 'Sin')
            xlabel(app.UIAxes, 'Sim Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end