classdef FOC_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        Lamp         matlab.ui.control.Lamp
        TextArea_2   matlab.ui.control.TextArea
        StopButton   matlab.ui.control.StateButton
        StartButton  matlab.ui.control.StateButton
        Image        matlab.ui.control.Image
        RightPanel   matlab.ui.container.Panel
        TextArea     matlab.ui.control.TextArea
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        device; % for serial port 
        SpeedDemand; % property to store speed demand of the motor 
        Operation; %Property to store operation type of motor 

    end
    
    methods (Access = private)
        
        function ComPortWarning(app)
            %warning window for invalid input
            opts = struct('WindowStyle','modal',... 
                  'Interpreter','tex');
            f = warndlg('\color{blue}The communication port has not been selected. Please select the appopiate port.',...
                'Invalid Input', opts);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
             % This function is triggered whenever the StartButton's state changes.
             % Retrieve the current value of the StartButton (true if pressed)
            value = app.StartButton.Value;
              % Check if the StartButton is currently pressed (value is true)
            if value 
                % Check if the 'device' property is not empty, indicating a device is connected
                if ~isempty(app.device)
                    % Prepare the message to send to the device
                    % Concatenating SpeedDemand and Operation into a single array
                    Message = [app.SpeedDemand,app.Operation]
                    % Send the prepared message to the connected device
                    write(app.device,Message,'single');
                    % Change the lamp color to green indicating successful operation
                    app.Lamp.Color = 'green';
                    %reset the StartButton's value to false after operations are complete
                    app.StartButton.Value = false;

                else
                    % If no device is connected, call the ComPortWarning function
                    % This function likely displays a warning message to the user
                    ComPortWarning(app);
                    % Change the lamp color to yellow indicating a warning or issue
                    app.Lamp.Color = 'yellow';
                    %reset the StartButton's value to false after operations are complete
                    app.StartButton.Value = false;
                 end
            end
            
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            if value 
                % Check if the 'device' property is not empty, indicating a device is connected
                if ~isempty(app.device)
                    %change operation
                    app.Operation = single(app.Operation-1);
                    %just in case set speed to 0
                    app.SpeedDemand = single(0);
                    % Prepare the message to send to the device
                            % Concatenating SpeedDemand and Operation into a single array
                    Message = [app.SpeedDemand;app.Operation];
                    % Send the prepared message to the connected device
                    write(app.device,Message,'single');
                    % Change the lamp color to green indicating successful operation
                    app.Lamp.Color = 'red';
                    %reset the StopButton's value to false after operations are complete
                    app.StopButton.Value = false;
                else
                     
                    % If no device is connected, call the ComPortWarning function
                    % This function likely displays a warning message to the user
                    ComPortWarning(app);
                    app.Lamp.Color = 'yellow';
                    %reset the StopButton's value to false after operations are complete
                    app.StopButton.Value = false;
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {677, 677};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {364, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'snapedit_1714763450173-removebg-preview (2).png';
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.TextArea_2 = uitextarea(app.LeftPanel);
            app.TextArea_2.Value = {'Control:'};
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'MATLAB FOC with field weakening'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FOC_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end