classdef SerialOpen_Loop_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotorControlUIFigure         matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ModelInfoTab                 matlab.ui.container.Tab
        Tree                         matlab.ui.container.Tree
        SelectCOMPortNode            matlab.ui.container.TreeNode
        SpeedRPMEditField            matlab.ui.control.NumericEditField
        SpeedRpmLabel                matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        SpeedRPMSlider               matlab.ui.control.Slider
        SpeedRPMSliderLabel          matlab.ui.control.Label
        ControlmechanismButtonGroup  matlab.ui.container.ButtonGroup
        DummyButton                  matlab.ui.control.ToggleButton
        FOCButton                    matlab.ui.control.ToggleButton
        OpenLoopButton               matlab.ui.control.ToggleButton
        DiagnosticsTab               matlab.ui.container.Tab
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        MotorControlPanel            matlab.ui.container.Panel
        Image_4                      matlab.ui.control.Image
        Image_3                      matlab.ui.control.Image
        Image_2                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        ProgressFig; 
        d;
        SpeedDemand;
        Operation;
        device;
        NewNode;
    end
    
    methods (Access = private)
        
        function ProgressBar(app)
            
            
                app.ProgressFig = uifigure('Position',[500,500,400,110], ...
                    'Name','Motor Control - dashboard', ...
                    'WindowStyle','modal','Icon','sq_logo.png');
           
                app.d = uiprogressdlg(app.ProgressFig,'Title', ...
                    'Progress bar', 'Message', ...
                'Loading the simulation','Indeterminate','on','Icon','sq_logo.png');
                drawnow
                pause(.5)
        end

        function PopulateCOMPorts(app)
            %Function to generate available tree nodes 

            %get available ports list 
            FreePorts = serialportlist("available");
            %populate the list if not empty 
            if ~isempty(FreePorts)
                for i=1:length(FreePorts)
                    app.NewNode{i} = uitreenode(app.SelectCOMPortNode);
                    app.NewNode{i}.Text = FreePorts{i};
                    %newNode = uitreenode('Text',FreePorts{i});
                    %app.SelectCOMPortNode.add(newNode);

                    
                end
            else
                    app.NewNode{1} = uitreenode(app.SelectCOMPortNode);
                    app.NewNode{1}.Text = "No Ports available";


            end


        end


        function ComPortWarning(app)
                %warning window for invalid input
            opts = struct('WindowStyle','modal',... 
                  'Interpreter','tex');
            f = warndlg('\color{blue}The communication port has not been selected. Please select the appopiate port.',...
                'Invalid Input', opts);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              %app.device = serialport("COM9",5e6);
        end

        % Selection changed function: ControlmechanismButtonGroup
        function ControlmechanismButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlmechanismButtonGroup.SelectedObject;
            %buttons
            OpenLoop = 'Open-Loop';
            FOC = 'FOC';
            device = serialport("COM9",5e6);
            % Set up communication port
            %app.device = serialport("COM7",5e6);
            % Enable the operation and create a message
            app.Operation = uint16(1);

            Message = [app.SpeedDemand,app.Operation];


            tf = strcmp(selectedButton.Text,OpenLoop);
            tf1 = strcmp(selectedButton.Text,FOC);

            if tf 
                %write(app.device,Message,'uint16');
                app.OpenLoopButton.Value = false;
                app.DummyButton.Value = true;
                if ~isempty(device)
                   write(device,Message,'uint16');
                   delete(device);
                   clear device;
                else
                     ComPortWarning(app);
                end

            elseif tf1
                a = 2;
                app.FOCButton.Value = false;
                app.DummyButton.Value = true;
            end


        end

        % Value changed function: SpeedRPMEditField
        function SpeedRPMEditFieldValueChanged(app, event)
            %get the speed demand and convert the value
            app.SpeedDemand = uint16(app.SpeedRPMEditField.Value);

            
        end

        % Value changed function: SpeedRPMSlider
        function SpeedRPMSliderValueChanged(app, event)
            app.SpeedDemand = uint16(app.SpeedRPMSlider.Value);
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             device = serialport("COM9",5e6);
            %change operation
            app.Operation = uint16(0);
            %just in case set speed to 0
            app.SpeedDemand = uint16(0);

            Message = [app.SpeedDemand,app.Operation];

            write(device,Message,'uint16');
            delete(device);
            clear device;
            %flush(app.device);
        end

        % Double-clicked callback: Tree
        function TreeDoubleClicked(app, event)
            %Value of the main tree node
            MainRoot = 'Select COM Port:';
            %get the value of selected node
            node = event.InteractionInformation.Node;
            %Genarate nodes based on the available COM ports
            PopulateCOMPorts(app);
            %compare the selected node and MainRoot
            tf = strcmp(node.Text,MainRoot);
            %if the port was selected then establish serial communication
            if ~tf
                % Set up communication port
                app.device = serialport(node.Text,5e6);
            end

        end

        % Button down function: DiagnosticsTab
        function DiagnosticsTabButtonDown(app, event)
            matrixsize = [600,2];
            AllElements = prod(matrixsize);
              %Predifine the time vector for 600 samples 
              Samples = 600;
              sampleInterval = 50e-6;% 50microseconds
              xData = (0:Samples-1)*sampleInterval;
            %Data receive 
            % Set up the figure and axis
           device = serialport("COM9", 5e6);
              
           
            Iaplot = plot(app.UIAxes,xData, NaN(Samples,1));  % Initialize an empty plot
                title(app.UIAxes, 'Ia (ADC counts)');
                xlabel(app.UIAxes, 'Time (ms)');
                ylabel(app.UIAxes,'Ia');

            Ibplot = plot(app.UIAxes_2,xData, NaN(Samples,1));
                 title(app.UIAxes_2, 'Ib (ADC counts)');
                 xlabel(app.UIAxes_2, 'Time (ms)');
                 ylabel(app.UIAxes_2,'Ib');


            %numofPoints = 600;
            try 
                while true 
                    %Read data as uint 16
                    data = read(device,AllElements,'uint16');
                    % data = rmoutliers(single(data));
                    %data = single(typecast(single(data), 'single'));
                    %reshape data into the desired matrix shape 
                     receivedData = reshape(data,[600,2]);
                    % currentIa = data(1:2:end);
                    % currentIb = data(2:2:end);
                 
                    currentIa = receivedData(:,1);
                    currentIb = receivedData(:,2);
                     currentIa = single(currentIa);
                     currentIb = single(currentIb);
                        % set(Iaplot, 'XData', linspace(0,100,600), 'YData', currentIa);
                    % set(Iaplot, 'XData', xData, 'YData', currentIa);
                    % 
                    % set(Ibplot, 'XData', xData, 'YData', currentIb);
                     % Update the plot data
                    Iaplot.YData = currentIa;
                    Ibplot.YData = currentIb;
                    % Force MATLAB to update the plot
                    drawnow limitrate;
                    currentIb =[];
                    currentIa = [];
                   
                end
            catch ME
                %clear app.device;
                rethrow(ME);
            end

           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MotorControlUIFigure = uifigure('Visible', 'off');
            app.MotorControlUIFigure.Name = 'MotorControl';
            app.MotorControlUIFigure.Icon = 'sq_logo.jpg';
            app.MotorControlPanel = uipanel(app.MotorControlUIFigure);
            app.MotorControlPanel.Title = 'Motor Control ';
            app.Image_2 = uiimage(app.MotorControlPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Pics', 'UOSLogo_Primary_Violet_RGB.png');
            app.Image_3 = uiimage(app.MotorControlPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Pics', 'UOSLogo_Primary_Violet_RGB.png');
            app.Image_4 = uiimage(app.MotorControlPanel);
            app.Image_4.ImageSource = 'UOSLogo_Primary_Violet_RGB.png';
            app.TabGroup = uitabgroup(app.MotorControlUIFigure);
            app.ModelInfoTab = uitab(app.TabGroup);
            app.ModelInfoTab.Title = 'Model Info';
            app.ControlmechanismButtonGroup = uibuttongroup(app.ModelInfoTab);
            app.ControlmechanismButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlmechanismButtonGroupSelectionChanged, true);
            app.ControlmechanismButtonGroup.Title = 'Control mechanism';
            app.OpenLoopButton = uitogglebutton(app.ControlmechanismButtonGroup);
            app.OpenLoopButton.Text = 'Open-Loop';
            app.FOCButton = uitogglebutton(app.ControlmechanismButtonGroup);
            app.FOCButton.Text = 'FOC';
            app.DummyButton = uitogglebutton(app.ControlmechanismButtonGroup);
            app.DummyButton.Enable = 'off';
            app.DummyButton.Visible = 'off';
            app.DummyButton.Text = 'Dummy';
            app.DummyButton.Value = true;
            app.SpeedRPMSliderLabel = uilabel(app.ModelInfoTab);
            app.SpeedRPMSliderLabel.Text = 'Speed (RPM)';
            app.SpeedRPMSlider = uislider(app.ModelInfoTab);
            app.SpeedRPMSlider.Limits = [0 4000];
            app.SpeedRPMSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedRPMSliderValueChanged, true);
            app.StopButton = uibutton(app.ModelInfoTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SpeedRpmLabel = uilabel(app.ModelInfoTab);
            app.SpeedRpmLabel.Text = 'Speed (RPM)';
            app.SpeedRPMEditField = uieditfield(app.ModelInfoTab, 'numeric');
            app.SpeedRPMEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedRPMEditFieldValueChanged, true);
            app.Tree = uitree(app.ModelInfoTab);
            app.Tree.DoubleClickedFcn = createCallbackFcn(app, @TreeDoubleClicked, true);
            app.SelectCOMPortNode = uitreenode(app.Tree);
            app.SelectCOMPortNode.Icon = 'ComPort.png';
            app.SelectCOMPortNode.Text = 'Select COM Port:';
            app.DiagnosticsTab = uitab(app.TabGroup);
            app.DiagnosticsTab.Title = 'Diagnostics';
            app.DiagnosticsTab.ButtonDownFcn = createCallbackFcn(app, @DiagnosticsTabButtonDown, true);
            app.UIAxes = uiaxes(app.DiagnosticsTab);
            app.UIAxes_2 = uiaxes(app.DiagnosticsTab);
            % Show the figure after all components are created
            app.MotorControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialOpen_Loop_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotorControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotorControlUIFigure)
        end
    end
end